classdef Mohrs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MohrsCircleUIFigure          matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        InputsMPaPanel               matlab.ui.container.Panel
        ShowValuesonGraphCheckBox    matlab.ui.control.CheckBox
        ShowAngleofRotationCheckBox  matlab.ui.control.CheckBox
        ShowColouredPlotsCheckBox    matlab.ui.control.CheckBox
        ThetaSliderLabel             matlab.ui.control.Label
        ThetaSlider                  matlab.ui.control.Slider
        AngleofRotationEditField     matlab.ui.control.NumericEditField
        TypeButtonGroup              matlab.ui.container.ButtonGroup
        button2D                     matlab.ui.control.RadioButton
        button3D                     matlab.ui.control.RadioButton
        OutputsMPaPanel              matlab.ui.container.Panel
        RotatedPrincipleLabel        matlab.ui.control.Label
        RotatedPrincipleEditField    matlab.ui.control.NumericEditField
        RotatedShearEditFieldLabel   matlab.ui.control.Label
        RotatedShearEditField        matlab.ui.control.NumericEditField
        VonMisesEditFieldLabel       matlab.ui.control.Label
        VonMisesEditField            matlab.ui.control.NumericEditField
        NormalStressxEditFieldLabel  matlab.ui.control.Label
        NormalStressxEditField       matlab.ui.control.NumericEditField
        NormalStressyEditFieldLabel  matlab.ui.control.Label
        NormalStressyEditField       matlab.ui.control.NumericEditField
        ShearStressxyEditFieldLabel  matlab.ui.control.Label
        ShearStressxyEditField       matlab.ui.control.NumericEditField
        PlotButton                   matlab.ui.control.Button
        MaxPrincipleEditFieldLabel   matlab.ui.control.Label
        MaxPrincipleEditField        matlab.ui.control.NumericEditField
        MinPrincipleEditFieldLabel   matlab.ui.control.Label
        MinPrincipleEditField        matlab.ui.control.NumericEditField
        MaxShearEditFieldLabel       matlab.ui.control.Label
        MaxShearEditField            matlab.ui.control.NumericEditField
        MinShearEditFieldLabel       matlab.ui.control.Label
        MinShearEditField            matlab.ui.control.NumericEditField
        ShearStressyzEditFieldLabel  matlab.ui.control.Label
        ShearStressyzEditField       matlab.ui.control.NumericEditField
        ShearStressxzEditFieldLabel  matlab.ui.control.Label
        ShearStressxzEditField       matlab.ui.control.NumericEditField
        NormalStresszEditFieldLabel  matlab.ui.control.Label
        NormalStresszEditField       matlab.ui.control.NumericEditField
        RightPanel                   matlab.ui.container.Panel
        PrincipalInterestUIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        % Circle drawing function
        function h = circle(app,x,y,r,s)
            th = 0:pi/50:2*pi;
            xunit = r * cos(th) + x ;
            yunit = r * sin(th) + y;
            h = plot(app.PrincipalInterestUIAxes, xunit, yunit, s);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MohrsCircleUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {498, 498};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {352, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: NormalStressxEditField, PlotButton, 
        % PrincipalInterestUIAxes
        function PlotButtonPushed(app, event)
            selectedButton = app.TypeButtonGroup.SelectedObject;
            if selectedButton == app.button2D
                % 2D Mohrs circle
                %Input values
                N_stress_x = app.NormalStressxEditField.Value;
                N_stress_y = app.NormalStressyEditField.Value;
                S_stress_yface = app.ShearStressxyEditField.Value;
                Theta = app.ThetaSlider.Value;
                
                % Calculations
                % principal and shear stress
                cx = (N_stress_y+N_stress_x)/2;
                cy = 0;
                r = sqrt(((N_stress_x-cx)^2)+(S_stress_yface^2));
                %                 r = sqrt(((N_stress_x-N_stress_y)/2)^2+(S_stress_yface^2));
                ThetaI = atand((S_stress_yface)/(N_stress_x-cx));
                Mx_prinS = cx+r;
                Mn_prinS = cx-r;
                Mx_Shear = cy+r;
                Mn_Shear = cy-r;
                % Von mises
                vm = sqrt(0.5*((Mx_prinS-Mn_prinS)^2 + Mx_prinS^2 + Mn_prinS^2));
                % Tresca
                % Tresca = 2*Mx_Shear;
                % Rotated element
                ThetaT = (Theta)+ThetaI;
                ya = -r*sind(ThetaT)+cy;
                xa = r*cosd(ThetaT)+cx;
                yb = -ya;
                xb = cx-(xa-cx);
                arcr = r/5;
                arcth = deg2rad(ThetaI):pi/100:deg2rad(ThetaT);
                [xx, yy] = pol2cart(arcth,arcr);
                xx(2:end+1) = xx(1:end);
                xx(1) = 0;
                xx(end) = 0;
                yy(2:end+1) = yy(1:end);
                yy(1) = 0;
                yy(end) = 0;
                [tt, hh] = pol2cart(arcth(length(arcth/2)),arcr+r/10);
                
                % rotated stress output
                rtau = abs(ya(1:1));
                rsig = abs(xa(1:1));
                
                
                % Outputs
                app.AngleofRotationEditField.Value = Theta;
                app.AngleofRotationEditField.Value = Theta;
                app.MaxPrincipleEditField.Value = Mx_prinS;
                app.MinPrincipleEditField.Value = Mn_prinS;
                app.MaxShearEditField.Value = Mx_Shear;
                app.MinShearEditField.Value = Mn_Shear;
                app.RotatedShearEditField.Value = rtau;
                app.RotatedPrincipleEditField.Value = rsig;
                app.VonMisesEditField.Value = vm;
                %app.TrescaTensileEditField.Value = Tresca;
                
                % Plot 2D mohrs circle
                % Axes setup
                % clear axes
                cla(app.PrincipalInterestUIAxes)
                % x-axis
                plot(app.PrincipalInterestUIAxes, [cx-r-r/2, cx+r+r/2], [0, 0], 'linestyle', "-", "Color", 'Black', "LineWidth", 1);
                % y-axis
                plot(app.PrincipalInterestUIAxes, [0 0], [cy-r-r/2, cy+r+r/2], 'linestyle', '-', "Color", 'Black', "LineWidth", 1);
                % flip y-axis
                set(app.PrincipalInterestUIAxes, 'YDir','reverse')
                % axis limits
                axis(app.PrincipalInterestUIAxes, [cx-r-2*r/5, cx+r+2*r/5, cy-r-2*r/5, cy+r+2*r/5])
                
                % Mohrs Plots
                % Colour check
                if app.ShowColouredPlotsCheckBox.Value == 1
                    C1 = 'Blue';
                    C2 = 'Red';
                    C3 = 'Green';
                else
                    C1 = 'Black';
                    C2 = 'Black';
                    C3 = 'Black';
                end
                % Circle
                circle(app,cx,cy,r,C1);
                % Original
                p2 = plot(app.PrincipalInterestUIAxes,[N_stress_x, N_stress_y], [-S_stress_yface,S_stress_yface], 'linestyle',':','color', C2);
                % Principle
                p3 = plot(app.PrincipalInterestUIAxes, [Mn_prinS, Mx_prinS], [cy, cy], 'linestyle', '-', 'color', C3);
                % Shear
                p4 = plot(app.PrincipalInterestUIAxes, [cx, cx],[Mx_Shear, Mn_Shear],'linestyle', '--', 'color', 'Black');
                                
                % Salient points
                plot(app.PrincipalInterestUIAxes, cx, Mx_Shear, cx, Mn_Shear, 'Marker', 'o', 'MarkerSize', 6, 'color', 'Black');
                plot(app.PrincipalInterestUIAxes, Mx_prinS, cy, Mn_prinS, cy, 'Marker', 'o', 'MarkerSize', 6, 'color', 'Black');
                % Graph labelling
                txt1 = sprintf('%g^{o}', round(app.AngleofRotationEditField.Value,2));
                %txt2 = sprintf('(%g)\nMax Prin\nStress', round(Mx_prinS,2));
                %txt3 = sprintf('(%g)\nMin Prin\nStress', round(Mn_prinS,2));
                txt2 = sprintf('(%g) %c_2', round(Mx_prinS,2), 963);         % lowercase sigma character(963)
                txt3 = sprintf('(%g) %c_1', round(Mn_prinS,2), 963);
                txt4 = sprintf('(%g)\nMax Shear\nStress', round(Mx_Shear,2));
                txt5 = sprintf('(%g)\nMin Shear\nStress', round(Mn_Shear,2));
                if app.ShowValuesonGraphCheckBox.Value == 1
                    text(app.PrincipalInterestUIAxes, Mx_prinS, -r/10, txt2, 'Fontsize', 10)
                    text(app.PrincipalInterestUIAxes, Mn_prinS-r/10 - r/4 , -r/10, txt3, 'Fontsize', 10)
                    text(app.PrincipalInterestUIAxes, cx, Mx_Shear+r/5, txt4, 'Fontsize', 10)
                    text(app.PrincipalInterestUIAxes, cx, Mn_Shear-r/5, txt5, 'Fontsize', 10)
                end
                % Angle of rotation
                if app.ShowAngleofRotationCheckBox.Value == 1 && app.AngleofRotationEditField.Value ~= 0 && app.ThetaSlider.Value ~= 0
                    if app.ShowColouredPlotsCheckBox.Value == 1
                        p10 = patch(app.PrincipalInterestUIAxes, (xx)+cx, (-yy)+cy,'green','edgecolor','none');
                    % rotated element line
                        if Theta ~= 0
                            rl = plot(app.PrincipalInterestUIAxes, [xa, xb],[ya, yb],'linestyle',"-.","Color", 'green');
                            plot(app.PrincipalInterestUIAxes, xa, ya, xb, yb, 'Marker', 'o', "MarkerSize",6, "Color",'green');
                        end
                    else
                        p10 = patch(app.PrincipalInterestUIAxes, (xx)+cx, (-yy)+cy,'black','edgecolor','none');
                        % rotated element line    
                            if Theta ~= 0
                                rl = plot(app.PrincipalInterestUIAxes, [xa, xb],[ya, yb],'linestyle',"-.","Color", 'black');
                                plot(app.PrincipalInterestUIAxes, xa, ya, xb, yb, 'Marker', 'o', "MarkerSize",6, "Color",'black');
                            end
                        % p10 = patch(app.PrincipalInterestUIAxes, xx+cx, -yy+cy,'color', '#808080', 'edgecolor', 'none');
                    end
                end
                
                % Legend Decision
                if Theta == 0
                    legend(app.PrincipalInterestUIAxes, [p2,p3,p4], {'Original', 'Principle', 'Shear'}, 'Location','Northoutside', "Orientation","horizontal");
                elseif Theta ~= 0 && app.ShowAngleofRotationCheckBox.Value == 1
                    legend(app.PrincipalInterestUIAxes,[p2,p3,p4,rl,p10], {'Original','Principle', 'Shear','Rotated Element','Angle of Rotation'},'Location','Northoutside',"NumColumns",2, 'Orientation','horizontal');
                    text(app.PrincipalInterestUIAxes, tt+cx, -hh+cy, txt1, 'fontsize', 10)
                elseif Theta ~= 0 && app.ShowAngleofRotationCheckBox.Value == 0
                    legend(app.PrincipalInterestUIAxes,[p2,p3,p4,rl], {'Original','Principle', 'Shear','Rotated Element'},'Location','Northoutside',"NumColumns",2, 'Orientation','horizontal');
                end
            elseif selectedButton == app.button3D
                % 3D mohrs circle
                % Input Values
                N_stress_x = app.NormalStressxEditField.Value;
                N_stress_y = app.NormalStressyEditField.Value;
                N_stress_z = app.NormalStresszEditField.Value;
                S_stress_xy = app.ShearStressxyEditField.Value;
                S_stress_xz = app.ShearStressxzEditField.Value;
                S_stress_yz = app.ShearStressyzEditField.Value;
                                
                % Calculations
                if N_stress_x>N_stress_y && N_stress_y>N_stress_z
                    g1 = N_stress_x;
                    g2 = N_stress_y;
                    g3 = N_stress_z;
                    t1 = "xy plane";
                    t2 = "yz plane";
                    t3 = "xz plane";
                elseif N_stress_x>N_stress_z && N_stress_z>N_stress_y
                    g1 = N_stress_x;
                    g2 = N_stress_z;
                    g3 = N_stress_y;
                    t1 = "xz plane";
                    t2 = "zy plane";
                    t3 = "xy plane";
                elseif N_stress_y>N_stress_x && N_stress_x>N_stress_z
                    g1 = N_stress_y;
                    g2 = N_stress_x;
                    g3 = N_stress_z;
                    t1 = "yx plane";
                    t2 = "yz plane";
                    t3 = "yz plane";
                elseif N_stress_y>N_stress_z && N_stress_z>N_stress_x
                    g1 = N_stress_y;
                    g2 = N_stress_z;
                    g3 = N_stress_x;
                    t1 = "yz plane";
                    t2 = "yx plane";
                    t3 = "zx plane";
                elseif N_stress_z>N_stress_y && N_stress_y>N_stress_x
                    g1 = N_stress_z;
                    g2 = N_stress_y;
                    g3 = N_stress_x;
                    t1 = "zy plane";
                    t2 = "zx plane";
                    t3 = "yx plane";
                elseif N_stress_z>N_stress_x && N_stress_x>N_stress_y
                    g1 = N_stress_z;
                    g2 = N_stress_x;
                    g3 = N_stress_y;
                    t1 = "zx plane";
                    t2 = "zy plane";
                    t3 = "xy plane";
                end
                
                % Principal Stresses
                %P^3 - (I1*(P^2)) + (I2*P) - I3 = 0;
                
                StressTensor = [N_stress_x, S_stress_xy, S_stress_xz;
                    S_stress_xy, N_stress_y, S_stress_yz;
                    S_stress_xz, S_stress_yz, N_stress_z];
                lambda = eig(StressTensor);
                Prin_stress_x = max(lambda);
                Prin_stress_y = median(lambda);
                Prin_stress_z = min(lambda);
                
                % Centres and radii
                c1 = (Prin_stress_y + Prin_stress_z)/2;
                c2 = (Prin_stress_x + Prin_stress_z)/2;
                c3 = (Prin_stress_x + Prin_stress_y)/2;
                r1 = (abs(Prin_stress_y - Prin_stress_z))/2;
                r2 = (abs(Prin_stress_x - Prin_stress_z))/2;
                r3 = (abs(Prin_stress_x - Prin_stress_y))/2;
                centres = [c1,c2,c3];
                radii = [r1,r2,r3];
                sigmatot = [Prin_stress_z, Prin_stress_y, Prin_stress_x];
                % Min/Max
                sig_min = min(sigmatot);
                sig_max = max(sigmatot);
                tau_max = + max(radii);
                tau_min = - max(radii);
                % total radius
                rtot = sig_max-sig_min;
                % Von mises
                vm = (0.5*(((N_stress_x-N_stress_y)^2)+((N_stress_y-N_stress_z)^2)+((N_stress_z-N_stress_x)^2)/2)+(3*((S_stress_xy*S_stress_xy)+(S_stress_yz*S_stress_yz)+(S_stress_xz*S_stress_xz))))^(1/2);
                % Tresca
                %Tresca = tau_max*2;
                % Output values
                app.MaxPrincipleEditField.Value = sig_max;
                app.MinPrincipleEditField.Value = sig_min;
                app.MaxShearEditField.Value = tau_max;
                app.MinShearEditField.Value = tau_min;
                app.VonMisesEditField.Value = vm;
                %app.TrescaTensileEditField.Value = Tresca;
                
                % Rotation
                %                a = 60;
                %                x1 = [((tand(a)^2)+1), -2*((tand(a)^2)+Prin_stress_x), ((Prin_stress_x^2)*((tand(a)^2)+1)-(r1^2))];
                %                rootx1 = roots(x1);
                %                k2 = max(rootx1);
                %                x3 = [((tand(a)^2)+1), -2*((tand(a)^2)+Prin_stress_z), ((Prin_stress_z^2)*((tand(a)^2)+1)-(r3^2))];
                %                rootx3 = roots(x3);
                %                k1 = max(rootx3);
                % Plot 3D Mohrs Circle
                % Axes setup
                % clear Axes
                cla(app.PrincipalInterestUIAxes)
                % x-axis
                plot(app.PrincipalInterestUIAxes, [sig_min - 50, sig_max + 50], [0, 0], 'linestyle', "-", "Color", 'Black', "LineWidth", 1);
                % y-axis
                plot(app.PrincipalInterestUIAxes, [0, 0], [-(max(radii) + 50), max(radii) + 50], 'linestyle', "-", "Color", 'Black', "LineWidth", 1);
                % Flip y-axis
                set(app.PrincipalInterestUIAxes, 'YDir', 'reverse')
                % size
                axis(app.PrincipalInterestUIAxes, [tau_min - 40, tau_max + 40, -max(radii) - 20, max(radii) + 20])
                
                % Plot circles
                % Colours
                C1 = 'Black';
                C2 = 'Black';
                C3 = 'Black';
                if app.ShowColouredPlotsCheckBox.Value == 1
                    C1 = 'Blue';
                    C2 = 'Red';
                    C3 = 'Green';                  
                end
                
                % Circles
                p2 = circle(app, c2, 0, r2, C3);   % Green
                p1 = circle(app, c1, 0, r1, C1);    % Blue
                p3 = circle(app, c3, 0, r3, C2);     % Red
                
                % Salient points
                plot(app.PrincipalInterestUIAxes, sig_max, 0, sig_min, 0, 'Marker', 'o', 'Markersize', 6, 'color','Black')
                plot(app.PrincipalInterestUIAxes, c2, tau_max, c2, tau_min, 'Marker', 'o', 'Markersize', 6, 'color', 'Black')
                %plot(app.PrincipalInterestUIAxes, k1,k2,'Marker', 'o', 'Markersize', 6, 'color', 'Black')
                
                % graph labelling
                txt6 = sprintf('(%g)\nMax Shear\nStress', round(tau_max,2));
                txt7 = sprintf('(%g)\nMin Shear\nStress', round(tau_min,2));
                txt8 = sprintf('(%g) %c_2', round(sig_max,2),963);
                txt9 = sprintf('(%g) %c_1', round(sig_min,2),963);
                if app.ShowValuesonGraphCheckBox.Value == 1
                    text(app.PrincipalInterestUIAxes, c2,tau_max+rtot/10, txt6, 'Fontsize', 10)
                    text(app.PrincipalInterestUIAxes, c2,tau_min-rtot/10, txt7, 'Fontsize', 10)
                    text(app.PrincipalInterestUIAxes, sig_max+rtot/10-15, -rtot/20, txt8, 'Fontsize', 10)
                    text(app.PrincipalInterestUIAxes, sig_min-rtot/10-20, -rtot/20, txt9, 'Fontsize', 10)
                end
                % Legend
                hLeg = legend(app.PrincipalInterestUIAxes, [p1, p2, p3], [t1, t2, t3],'Location','Northoutside',"NumColumns",2, 'Orientation','horizontal');
                if app.ShowColouredPlotsCheckBox == 1
                    set(hLeg,'visible','on')
                else
                    set(hLeg,'visible','off')
                end
                    axis(app.PrincipalInterestUIAxes, [sig_min - 40, sig_max + 40, -max(radii) - 40, max(radii) + 40])
                
            end
            
        end

        % Value changed function: AngleofRotationEditField
        function AngleofRotationEditFieldValueChanged(app, event)
            value = app.AngleofRotationEditField.Value;
            if value > 90
                
            end
            app.ThetaSlider.Value = value;
            
        end

        % Key press function: MohrsCircleUIFigure
        function MohrsCircleUIFigureKeyPress(app, event)
            % allows enter key to be used to plot
            key = event.Key;
            if(strcmp (key , 'return'))
                app.PlotButtonPushed
            end
        end

        % Selection changed function: TypeButtonGroup
        function TypeButtonGroupSelectionChanged(app, event)
            % 2D or 3D mode selection
            % Changes visibility of accesible fields given selection
            selectedButton = app.TypeButtonGroup.SelectedObject;
            if selectedButton == app.button2D
                app.ShearStressxzEditField.Visible = 'off';
                app.ShearStressxzEditFieldLabel.Visible = 'off';
                app.ShearStressyzEditField.Visible = 'off';
                app.ShearStressyzEditFieldLabel.Visible = 'off';
                app.NormalStresszEditField.Visible = 'off';
                app.NormalStresszEditFieldLabel.Visible = 'off';
                app.AngleofRotationEditField.Visible = 'on';
                %app.AngleofRotationEditFieldLabel.Visible ='on'; % Label already invisible 
                app.RotatedPrincipleEditField.Visible = 'on';
                app.RotatedPrincipleLabel.Visible = 'on';
                app.RotatedShearEditField.Visible = 'on';
                app.RotatedShearEditFieldLabel.Visible = 'on';
                app.ThetaSlider.Visible = 'on';
                app.ThetaSliderLabel.Visible = 'on';
                app.ShowAngleofRotationCheckBox.Visible = 'on';
                
            elseif selectedButton == app.button3D
                app.ShearStressxzEditField.Visible = 'on';
                app.ShearStressxzEditFieldLabel.Visible = 'on';
                app.ShearStressyzEditField.Visible = 'on';
                app.ShearStressyzEditFieldLabel.Visible = 'on';
                app.NormalStresszEditField.Visible = 'on';
                app.NormalStresszEditFieldLabel.Visible = 'on';
                app.AngleofRotationEditField.Visible = 'off';
                %app.AngleofRotationEditFieldLabel.Visible = 'off';
                app.RotatedShearEditField.Visible = 'off';
                app.RotatedShearEditFieldLabel.Visible = 'off';
                app.RotatedPrincipleEditField.Visible = 'off';
                app.RotatedPrincipleLabel.Visible = 'off';
                app.ThetaSlider.Visible = 'off';
                app.ThetaSliderLabel.Visible = 'off';
                app.ShowAngleofRotationCheckBox.Visible = 'off';
            end
        end

        % Callback function
        function ShowAngleofRotationCheckBoxValueChanged(app, event)
            
            value = app.ShowAngleofRotationCheckBox.Value;
            
            if value == 1
                app.RotatedPrincipleEditField.Visible = 'on';
                app.RotatedPrincipleLabel.Visible = 'on';
                app.RotatedShearEditField.Visible = 'on';
                app.RotatedShearEditFieldLabel.Visible = 'on';
            elseif value == 0
                app.RotatedPrincipleEditField.Visible = 'off';
                app.RotatedPrincipleLabel.Visible = 'off';
                app.RotatedShearEditField.Visible = 'off';
                app.RotatedShearEditFieldLabel.Visible = 'off';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MohrsCircleUIFigure = uifigure('Visible', 'off');
            app.MohrsCircleUIFigure.Name = 'Mohrs Circle';
            app.MohrsCircleUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.MohrsCircleUIFigure.KeyPressFcn = createCallbackFcn(app, @MohrsCircleUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.MohrsCircleUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputsMPaPanel = uipanel(app.LeftPanel);
            app.InputsMPaPanel.Title = 'Inputs (MPa)';
            app.ShowValuesonGraphCheckBox = uicheckbox(app.InputsMPaPanel);
            app.ShowValuesonGraphCheckBox.Text = 'Show Values on Graph';
            app.ShowAngleofRotationCheckBox = uicheckbox(app.InputsMPaPanel);
            app.ShowAngleofRotationCheckBox.Text = 'Show Angle of Rotation';
            app.ShowColouredPlotsCheckBox = uicheckbox(app.InputsMPaPanel);
            app.ShowColouredPlotsCheckBox.Text = 'Show Coloured Plots';
            app.ThetaSliderLabel = uilabel(app.InputsMPaPanel);
            app.ThetaSliderLabel.Text = 'Theta';
            app.ThetaSlider = uislider(app.InputsMPaPanel);
            app.ThetaSlider.Limits = [-90 90];
            app.ThetaSlider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.AngleofRotationEditField = uieditfield(app.InputsMPaPanel, 'numeric');
            app.AngleofRotationEditField.ValueDisplayFormat = '%8.f';
            app.AngleofRotationEditField.ValueChangedFcn = createCallbackFcn(app, @AngleofRotationEditFieldValueChanged, true);
            app.TypeButtonGroup = uibuttongroup(app.InputsMPaPanel);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeButtonGroupSelectionChanged, true);
            app.TypeButtonGroup.Title = 'Type';
            app.TypeButtonGroup.Tag = 'RadioButton';
            app.button2D = uiradiobutton(app.TypeButtonGroup);
            app.button2D.Text = '2D';
            app.button3D = uiradiobutton(app.TypeButtonGroup);
            app.button3D.Text = '3D';
            app.button3D.Value = true;
            app.OutputsMPaPanel = uipanel(app.LeftPanel);
            app.OutputsMPaPanel.Title = 'Outputs (MPa)';
            app.RotatedPrincipleLabel = uilabel(app.OutputsMPaPanel);
            app.RotatedPrincipleLabel.Text = 'Rotated Principle';
            app.RotatedPrincipleEditField = uieditfield(app.OutputsMPaPanel, 'numeric');
            app.RotatedShearEditFieldLabel = uilabel(app.OutputsMPaPanel);
            app.RotatedShearEditFieldLabel.Text = 'Rotated Shear';
            app.RotatedShearEditField = uieditfield(app.OutputsMPaPanel, 'numeric');
            app.VonMisesEditFieldLabel = uilabel(app.OutputsMPaPanel);
            app.VonMisesEditFieldLabel.Text = 'Von Mises';
            app.VonMisesEditField = uieditfield(app.OutputsMPaPanel, 'numeric');
            app.NormalStressxEditFieldLabel = uilabel(app.LeftPanel);
            app.NormalStressxEditFieldLabel.Text = 'Normal Stress x';
            app.NormalStressxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NormalStressxEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.NormalStressxEditField.Value = 100;
            app.NormalStressyEditFieldLabel = uilabel(app.LeftPanel);
            app.NormalStressyEditFieldLabel.Text = 'Normal Stress y';
            app.NormalStressyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NormalStressyEditField.Value = 10;
            app.ShearStressxyEditFieldLabel = uilabel(app.LeftPanel);
            app.ShearStressxyEditFieldLabel.Text = 'Shear Stress xy';
            app.ShearStressxyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ShearStressxyEditField.Value = 30;
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.MaxPrincipleEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxPrincipleEditFieldLabel.Text = 'Max Principle';
            app.MaxPrincipleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinPrincipleEditFieldLabel = uilabel(app.LeftPanel);
            app.MinPrincipleEditFieldLabel.Text = 'Min Principle';
            app.MinPrincipleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxShearEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxShearEditFieldLabel.Text = 'Max Shear';
            app.MaxShearEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinShearEditFieldLabel = uilabel(app.LeftPanel);
            app.MinShearEditFieldLabel.Text = 'Min Shear';
            app.MinShearEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ShearStressyzEditFieldLabel = uilabel(app.LeftPanel);
            app.ShearStressyzEditFieldLabel.Text = 'Shear Stress yz';
            app.ShearStressyzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ShearStressyzEditField.Value = 50;
            app.ShearStressxzEditFieldLabel = uilabel(app.LeftPanel);
            app.ShearStressxzEditFieldLabel.Text = 'Shear Stress xz';
            app.ShearStressxzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ShearStressxzEditField.Value = 20;
            app.NormalStresszEditFieldLabel = uilabel(app.LeftPanel);
            app.NormalStresszEditFieldLabel.Text = 'Normal Stress z';
            app.NormalStresszEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NormalStresszEditField.Value = 70;
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, 'Mohrs Circle')
            xlabel(app.PrincipalInterestUIAxes, 'Normal Stress')
            ylabel(app.PrincipalInterestUIAxes, 'Shear Stress')
            app.PrincipalInterestUIAxes.XLim = [0 100];
            app.PrincipalInterestUIAxes.YLim = [0 100];
            app.PrincipalInterestUIAxes.ButtonDownFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            % Show the figure after all components are created
            app.MohrsCircleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mohrs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MohrsCircleUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MohrsCircleUIFigure)
        end
    end
end